classdef HealthViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ShowTimeLabel       matlab.ui.control.Label
        Image               matlab.ui.control.Image
        Label_7             matlab.ui.control.Label
        UIText_SPO2         matlab.ui.control.TextArea
        Label_6             matlab.ui.control.Label
        UIText_HeartRate    matlab.ui.control.TextArea
        Label_5             matlab.ui.control.Label
        UIAxes_Humidity     matlab.ui.control.TextArea
        Label_4             matlab.ui.control.Label
        UIAxes_Temperature  matlab.ui.control.TextArea
        Label_3             matlab.ui.control.Label
        Lamp_2              matlab.ui.control.Lamp
        Lamp                matlab.ui.control.Lamp
        SerialSwitch        matlab.ui.control.Switch
        SerialState         matlab.ui.control.Lamp
        SerialDataWidth     matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        SerialParity        matlab.ui.control.DropDown
        DropDown_4Label     matlab.ui.control.Label
        SerialStopBit       matlab.ui.control.DropDown
        DropDown_3Label     matlab.ui.control.Label
        SerialBaud          matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        SerialPortNums      matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        Label_Project       matlab.ui.control.Label
        UIAxes_HeartSignal  matlab.ui.control.UIAxes
        UIAxes_SPO2         matlab.ui.control.UIAxes
        UIAxes_HeartRate    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialObject % 串口对象
        Temperature
        Humidity
        HeartSignal
        DiagnoseSignal
        HeartRate
        SPO2
        Hour      
        Minute
        Second
        X_HeartSignal
        X_HeartRate
        X_SPO2
        gyr_z

        num
        
        datetimer; % To show time
    end
    
    methods (Access = private)
        
        function serial_refresh(app)
            value = app.SerialSwitch.Value;
            if(value=='打开')%配置串口
               try
                com_list=serialportlist("all");
%                 com_list=bluetoothlist;
                app.SerialPortNums.Items=com_list; 
                delete(app.SerialObject);%删除之前打开的实例对象
                app.SerialState.Color=[0 1 0];
                app.SerialObject=serialport(...
                    app.SerialPortNums.Value,...%设置串口号
                    str2double(app.SerialBaud.Value),...%设置串口波特率
                    'DataBits',str2double(app.SerialDataWidth.Value),...%设置数据位宽
                    'StopBits',str2double(app.SerialStopBit.Value), ...%设置停止位
                    'Parity',app.SerialParity.Value);%设置奇偶校验位
                %configureTerminator(app.SerialObject,90);%读取和写入通信的终止符设置为0XA5
                %configureCallback(app.SerialObject,"terminator",@readSerialData);
                configureCallback(app.SerialObject,"byte",22,@readSerialData);
               catch
                app.SerialState.Color=[0.5 0.5 0.5];
                app.SerialSwitch.Value='关闭';
                msgbox('串口打开失败');
               end
               
                % 删除现存的定时器变量。这个函数也可以用来关闭定时器
                delete(timerfind);
                % 配置并开启显示时间的定时器
                app.datetimer = timer;
                % 开启定时器代码运行后，延时1s再调用回调函数
                app.datetimer.StartDelay = 1;
                % 定时器每隔1s调用一次回调函数
                app.datetimer.Period = 1;
                % 定时器的工作模式，读者可参考官方文档查阅不同模式的区别。此模式下，定时器会循环运行
                app.datetimer.ExecutionMode = 'fixedRate';
                app.datetimer.BusyMode = 'queue';
                app.datetimer.TimerFcn = @(~,~) app.datetimer_handler;
                start(app.datetimer);

            elseif(value=='关闭')
                delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                app.SerialState.Color=[0.5 0.5 0.5];
                % 删除现存的定时器变量。这个函数也可以用来关闭定时器
                delete(timerfind);
                evalin('base','save signal.mat HeartSignal') ;%保存心电信号
                evalin('base','coculate') ;%运行算法
            end
               %串口接收数据的回调函数
               function readSerialData(src,event)
%                    %cell_data=readline(app.SerialObject); 
%                    cell_data=read(app.SerialObject,22,"uint8"); 
%                    assignin("base","dataBuffer",cell_data);
% 
%                    app.X_HeartSignal=1:1:app.num;
%                    app.X_HeartRate=1:1:app.num;
%                    app.X_SPO2=1:1:app.num;
% 
%                    app.Temperature=cell_data(2);
%                    app.UIAxes_Temperature.Value=num2str(app.Temperature);
%                    assignin("base","Temperature",app.Temperature);
%                    
%                    app.Humidity=cell_data(3);
%                    app.UIAxes_Humidity.Value=num2str(app.Humidity);
%                    assignin("base","Humidity",app.Humidity);
%                    
%                    app.HeartSignal=[bitor(bitshift(cell_data(9),8),cell_data(10)) app.HeartSignal(1:app.num-1)];
%                    plot(app.UIAxes_HeartSignal,app.X_HeartSignal,app.HeartSignal)
%                    assignin("base","HeartSignal",app.HeartSignal);
%                    
%                    app.HeartRate=[bitor(bitshift(cell_data(11),8),cell_data(12)) app.HeartRate(1:app.num-1)];
%                    app.UIText_HeartRate.Value=num2str(bitor(bitshift(cell_data(11),8),cell_data(12)));
%                    plot(app.UIAxes_HeartRate,app.X_HeartRate,app.HeartRate)
%                    assignin("base","HeartRate",app.HeartRate);
%                    
%                    app.SPO2=[bitor(bitshift(cell_data(13),8),cell_data(14)) app.SPO2(1:app.num-1)];
%                    app.UIText_SPO2.Value=num2str(bitor(bitshift(cell_data(13),8),cell_data(14)));
%                    plot(app.UIAxes_SPO2,app.X_SPO2,app.SPO2)
%                    assignin("base","SPO2",app.SPO2);
%                    
%                    
%                      if(cell_data(4)==1)
%                         app.Lamp.Color=[0 1 0];    
%                      else
%                         app.Lamp.Color=[0.5 0.5 0.5];
%                      end
%                      
%                      if(cell_data(5)==1)
%                         app.Lamp_2.Color=[0 1 0];
%                      else
%                         app.Lamp_2.Color=[0.5 0.5 0.5];
%                      end

                end
        end


    end
    
    methods (Access = public)
        
        function datetimer_handler(app)
                CT = clock; % Current Time 当前时间
                set(app.ShowTimeLabel,"Text",['当前时间:',...
                num2str(CT(1)),'年',num2str(CT(2),"%02d"),'月',num2str(CT(3),"%02d"),'日 ',...
                num2str(CT(4),"%02d"),': ',num2str(CT(5),"%02d"),': ',num2str(CT(6),"%02.f")]);
                app.Second=app.Second+1;
                if(app.Second>60)
                    evalin('base','save signal.mat HeartSignal') ;%保存心电信号
                    evalin('base','coculate') ;%运行算法
                    app.Second=0;
                end
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            com_list=serialportlist("all");
            %com_list=bluetoothlist;
            app.SerialPortNums.Items=com_list;
            app.SerialBaud.Value='9600';
            app.SerialStopBit.Value='1' ;%
            app.SerialParity.Value="none";%无奇偶校验位
            app.SerialSwitch.Value='关闭';%默认关闭状态
            app.SerialState.Color=[0.5,0.5,0.5];%关闭状态时指示灯为绿色
            app.num=500;
            app.Temperature=zeros([1 app.num]);
            app.Humidity=zeros([1 app.num]);
            app.HeartSignal=zeros([1 app.num]);
            app.HeartRate=zeros([1 app.num]);
            app.SPO2=zeros([1 app.num]);
            app.Hour=zeros([1 app.num]);
            app.Minute=zeros([1 app.num]);
            app.Second=zeros([1 app.num]);
            app.gyr_z=zeros([1 app.num]);


        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            serial_refresh(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_HeartRate = uiaxes(app.UIFigure);
            title(app.UIAxes_HeartRate, '心率')
            zlabel(app.UIAxes_HeartRate, 'Z')
            app.UIAxes_HeartRate.XLim = [0 200];
            app.UIAxes_HeartRate.YLim = [0 200];
            app.UIAxes_HeartRate.YTick = [0 50 100 150 200];
            app.UIAxes_HeartRate.YTickLabel = {'0'; '50'; '100'; '150'; '200'};
            app.UIAxes_SPO2 = uiaxes(app.UIFigure);
            title(app.UIAxes_SPO2, '血氧饱和度')
            xlabel(app.UIAxes_SPO2, 't')
            zlabel(app.UIAxes_SPO2, 'Z')
            app.UIAxes_SPO2.XLim = [0 200];
            app.UIAxes_SPO2.YLim = [0 120];
            app.UIAxes_SPO2.YTick = [0 25 50 75 100];
            app.UIAxes_HeartSignal = uiaxes(app.UIFigure);
            title(app.UIAxes_HeartSignal, '心电信号')
            zlabel(app.UIAxes_HeartSignal, 'Z')
            app.UIAxes_HeartSignal.XLim = [0 500];
            app.UIAxes_HeartSignal.YLim = [0 400];
            app.UIAxes_HeartSignal.YTick = [0 100 200 300 400 500 600];
            app.Label_Project = uilabel(app.UIFigure);
            app.Label_Project.Text = '生理参数节点上位机';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口号';
            app.SerialPortNums = uidropdown(app.UIFigure);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '波特率';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '115200';
            app.DropDown_3Label = uilabel(app.UIFigure);
            app.DropDown_3Label.Text = '停止位';
            app.SerialStopBit = uidropdown(app.UIFigure);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.DropDown_4Label = uilabel(app.UIFigure);
            app.DropDown_4Label.Text = '校验位';
            app.SerialParity = uidropdown(app.UIFigure);
            app.SerialParity.Items = {'none', 'odd', 'even'};
            app.SerialParity.Value = 'none';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据位';
            app.SerialDataWidth = uidropdown(app.UIFigure);
            app.SerialDataWidth.Items = {'5', '6', '7', '8'};
            app.SerialDataWidth.Value = '8';
            app.SerialState = uilamp(app.UIFigure);
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'关闭', '打开'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '温度';
            app.UIAxes_Temperature = uitextarea(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '湿度';
            app.UIAxes_Humidity = uitextarea(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '心率';
            app.UIText_HeartRate = uitextarea(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = {'血氧'; ''};
            app.UIText_SPO2 = uitextarea(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = {'中国矿业大学'; '信息与控制工程学院'; '学生：杨振一'; '指导老师：袁小平'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '校徽.png';
            app.ShowTimeLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HealthViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end